classdef Motioncontrolv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        LockinPanel                   matlab.ui.container.Panel
        ResetGraphButton              matlab.ui.control.StateButton
        MoveandRecordButton           matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        MotionControlPanel            matlab.ui.container.Panel
        ResetButton                   matlab.ui.control.Button
        SetIncrementEditField         matlab.ui.control.NumericEditField
        SetIncrementEditFieldLabel    matlab.ui.control.Label
        MoveForwardbyIncrementButton  matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
    end

    
    properties (Access = private)
        motion; % NRT100
        forceSensor; % Lock in Amplifier
        lockin; % Lock in Object
        Amplitude = zeros(100,1); % List of Amplitude(R) displayed
        Position = zeros(100,1) % List of Position displayed
        counter = 1;
    end
    
    methods (Access = private)
        
        function StageActivation(app)
            fpos    = get(0,'DefaultFigurePosition'); % figure default position
            fpos(3) = 650*2/3; % figure window size;Width
            fpos(4) = 450*2/3; % Height

            fig = figure('Position', fpos,...
               'Menu','None',...
               'Name','APT GUI');
            app.motion = actxcontrol('MGMOTOR.MGMotorCtrl.1',[20 20 400 400*2/3 ], fig);
            app.motion.StartCtrl;
            SN2 = 90914852;
            set(app.motion,'HWSerialNum', SN2);
            jFrame2 = get(fig,'JavaFrame');
            jFrame2.setMinimized(true);
        end
        
        function LockIn(app)
            % Use instrfind to find existing instrument objects
            app.lockin = instrfind('Type', 'visa-gpib', 'RsrcName', 'GPIB0::8::INSTR', 'Tag', '');
            % If there are no existing objects q, create a new one
            if isempty(app.lockin)
                app.lockin = visa('ni', 'GPIB0::8::INSTR');
            end
            fopen(app.lockin);
        end
        
        function DisplayRecordAmp(app)
            app.motion.MoveRelative(0, true);
            pause(5);
            fprintf(app.lockin, 'OUTP? 3');
            app.Amplitude(app.counter) = fscanf(app.lockin, '%f');
            app.Position(app.counter) = app.motion.GetPosition_Position(0);
            xlabel(app.UIAxes,'Position');
            ylabel(app.UIAxes,'Amplitude');
            scatter(app.UIAxes,app.Position(1:app.counter), app.Amplitude(1:app.counter));
            app.counter = app.counter + 1;
            
        end
        function state = getMotorControlStatus(app)
            status = '0';
            LLGetStatusBits(channelID, status);
            if (bitget(status, 16) == 1) 
                state = 'CWMove';
            end 
            if (bitget(status, 32) == 1) 
                state = 'CCW';
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LockIn();
            app.StageActivation();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.motion.MoveHome(0,0);          
        end

        % Value changed function: SetIncrementEditField
        function SetIncrementEditFieldValueChanged(app, event)
            inc = app.SetIncrementEditField.Value;
            app.motion.SetVelParams(0,0, 0.5 , 1.5);
            app.motion.SetRelMoveDist(0, inc);
        end

        % Button pushed function: MoveForwardbyIncrementButton
        function MoveForwardbyIncrementButtonPushed(app, event)
            app.motion.MoveRelative(0, true);
        end

        % Button pushed function: MoveandRecordButton
        function MoveandRecordButtonPushed(app, event)
            app.DisplayRecordAmp();

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.lockin);
            delete(app);
            
        end

        % Value changed function: ResetGraphButton
        function ResetGraphButtonValueChanged(app, event)
            position = app.Position(1:app.counter);
            amplitude = app.Amplitude(1:app.counter);

            % Sort the data by ascending position
            [position_sorted, idx] = sort(position);
            amplitude_sorted = amplitude(idx);

            % Combine the sorted data into a matrix
            data = [position_sorted', amplitude_sorted'];

            % Save the sorted data into a CSV file
            filename = 'sorted_data.csv';
            writematrix(data, filename);
            app.Amplitude = zeros(100,1);
            app.Position = zeros(100,1);
            app.counter = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MotionControlPanel = uipanel(app.UIFigure);
            app.MotionControlPanel.Title = 'Motion Control';
            app.StartButton = uibutton(app.MotionControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.MoveForwardbyIncrementButton = uibutton(app.MotionControlPanel, 'push');
            app.MoveForwardbyIncrementButton.ButtonPushedFcn = createCallbackFcn(app, @MoveForwardbyIncrementButtonPushed, true);
            app.MoveForwardbyIncrementButton.Text = 'Move Forward by Increment';
            app.SetIncrementEditFieldLabel = uilabel(app.MotionControlPanel);
            app.SetIncrementEditFieldLabel.Text = 'Set Increment';
            app.SetIncrementEditField = uieditfield(app.MotionControlPanel, 'numeric');
            app.SetIncrementEditField.ValueChangedFcn = createCallbackFcn(app, @SetIncrementEditFieldValueChanged, true);
            app.SetIncrementEditField.Value = 3;
            app.ResetButton = uibutton(app.MotionControlPanel, 'push');
            app.ResetButton.Text = 'Reset';
            app.LockinPanel = uipanel(app.UIFigure);
            app.LockinPanel.Title = 'Lockin';
            app.UIAxes = uiaxes(app.LockinPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MoveandRecordButton = uibutton(app.LockinPanel, 'push');
            app.MoveandRecordButton.ButtonPushedFcn = createCallbackFcn(app, @MoveandRecordButtonPushed, true);
            app.MoveandRecordButton.Text = 'Move and Record ';
            app.ResetGraphButton = uibutton(app.LockinPanel, 'state');
            app.ResetGraphButton.ValueChangedFcn = createCallbackFcn(app, @ResetGraphButtonValueChanged, true);
            app.ResetGraphButton.Text = 'Reset Graph';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Motioncontrolv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end