classdef Motioncontrolv4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        MotionControlPanel      matlab.ui.container.Panel
        StartButton             matlab.ui.control.Button
        StopImmediateButton     matlab.ui.control.Button
        ExperimentalTestButton  matlab.ui.control.Button
        LockinPanel             matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
        MoveandRecordButton     matlab.ui.control.Button
        ResetGraphButton        matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        motion; % NRT100
        forceSensor; % Lock in Amplifier
        lockin; % Lock in Object
        Amplitude = zeros(1000,1); % List of Amplitude(R) displayed
        Position = zeros(1000,1) % List of Position displayed
        counter = 0; % Index for the Amplitude and Position Graph
        inc = 0.1;  %Increment for running the Motion Stage
        slope = zeros(1000,1); %Slope initialized in Experimental Test
        move = true; % When it sets to False, it stops the Experiemental Setup function
        
    end
    
    methods (Access = private)
        function WaitForMotionDone(app) % Wait until motion is complete
            while true
                status = app.motion.GetStatusBits_Bits(0); % Assuming channel 0
                if bitget(status, 11) % Check if bit 11 is 1 and 1 is true
                    break % Exit the loop
                end
                pause(0.1) % Wait for 0.1 seconds before checking again
            end
        end
        function StageActivation(app)
            fpos    = get(0,'DefaultFigurePosition'); % figure default position
            fpos(3) = 650*2/3; % figure window size;Width
            fpos(4) = 450*2/3; % Height

            fig = figure('Position', fpos,...
               'Menu','None',...
               'Name','APT GUI');

            app.motion = actxcontrol('MGMOTOR.MGMotorCtrl.1',[20 20 400 400*2/3 ], fig); %Creating motion app
            app.motion.StartCtrl;
            SN2 = 90914852; %The seriel number for the Channel connected
            set(app.motion,'HWSerialNum', SN2);
            jFrame2 = get(fig,'JavaFrame'); 
            jFrame2.setMinimized(true);

        end
        
        function LockIn(app)
            % Use instrfind to find existing instrument objects
            app.lockin = instrfind('Type', 'visa-gpib', 'RsrcName', 'GPIB0::8::INSTR', 'Tag', '');
            % If there are no existing objects q, create a new one
            if isempty(app.lockin)
                app.lockin = visa('ni', 'GPIB0::8::INSTR');
            end
            fopen(app.lockin);
            fprintf(app.lockin, 'OFLT 6'); % Set the time constant to 10ms
            fprintf(app.lockin, 'SLVL 0.1'); % Set the amplitude to 0.1V
            fprintf(app.lockin, 'OFSL 3'); % Low Pass Filter Slope
            fprintf(app.lockin, 'SYNC 1'); % Set Sync on (1)
            fprintf(app.lockin, 'SENS 23'); % Change for later 
            fprintf(app.lockin, 'HARM 1'); % Set Detection Harmonic (Value)
            fprintf(app.lockin, 'FREQ 100'); %Set Frequency 100 Hz
            
        end
        function DisplayRecordAmp(app)
            pause(1);
            app.counter = app.counter + 1;
            fprintf(app.lockin, 'OUTP? 3'); %Set OUTPUT to R value;
            app.Amplitude(app.counter) = fscanf(app.lockin, '%f'); % Read float values 
            app.Position(app.counter) = app.motion.GetPosition_Position(0); %Getting Position in mm
            xlabel(app.UIAxes,'Position');
            ylabel(app.UIAxes,'Amplitude');
            if (app.counter ~= 1) 
                app.slope(app.counter) = app.FindSlope();
            end
            scatter(app.UIAxes,app.Position(1:app.counter), app.Amplitude(1:app.counter));
            
        end
        function Optimizetest(app)
            app.move =true;
            app.DisplayRecordAmp(); % Plot amplitude and position
            app.motion.MoveRelative(0, true); 
            app.DisplayRecordAmp();
            app.motion.MoveRelative(0, true); 
            app.DisplayRecordAmp();
            
            while(app.slope(app.counter) >= 0.9 * app.slope(app.counter -1) || app.Amplitude(app.counter) < 0.0003)
                if(app.move == false)
                    break;
                end
                app.motion.MoveRelative(0, true);
                pause(1);
                app.DisplayRecordAmp();
            end
            app.motion.SetRelMoveDist(0, -1.5 * app.inc);
            app.motion.MoveRelative(0,true);
            app.DisplayRecordAmp();
            app.motion.SetRelMoveDist(0, 0.005);
            for c = 1:30
                if(app.move == false)
                    break;
                end
                app.motion.MoveRelative(0,true);
                app.DisplayRecordAmp();
                
                %acc = (app.slope - Prevslope) / (app.Position(app.counter) - app.Position(app.counter - 1));
                %if ( acc < 0 || (acc > 10 && app.inc < 0))
                   % app.inc = app.inc * -0.5;
                %end
            end
            coef = polyfit(app.Position(app.counter - 29: app.counter), app.slope(app.counter - 29: app.counter), 2);
            pos = -coef(2)/ (2* coef(1));
            csvwrite('solution',pos);
        end
        function newslope = FindSlope(app)
            newslope = (app.Amplitude(app.counter) - app.Amplitude(app.counter -1)) / (app.Position(app.counter) - app.Position(app.counter -1));
        end
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LockIn();
            app.StageActivation();
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.motion.SetAbsMovePos(0, 90);
            app.motion.SetVelParams(0,0, 0.5 , 2.0);
            app.motion.SetRelMoveDist(0, app.inc);
            app.motion.MoveHome(0,true); 
            %app.WaitForMotionDone();
            app.motion.MoveAbsolute(0,true);
        end

        % Button pushed function: MoveandRecordButton
        function MoveandRecordButtonPushed(app, event)
            app.motion.MoveRelative(0, true);
            pause(3);
            app.DisplayRecordAmp();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.lockin);
            delete(app.motion);
            delete(app);
            
        end

        % Value changed function: ResetGraphButton
        function ResetGraphButtonValueChanged(app, event)
            position = app.Position(2:app.counter);
            amplitude = app.Amplitude(2:app.counter);
            
            % Sort the data by ascending position
            %[position_sorted, idx] = sort(position);
            %amplitude_sorted = amplitude(idx);
            
            % Combine the sorted data into a matrix
            data = [amplitude' position'];
            
            % Save the sorted data into a CSV file
            filename = 'sorted_data.csv';
            csvwrite(filename, data); 
            app.Amplitude = zeros(100,1);
            app.Position = zeros(100,1);
            app.counter = 1;
            app.inc = 0.2;
            app.motion.SetRelMoveDist(0, app.inc);
        end

        % Button pushed function: StopImmediateButton
        function StopImmediateButtonPushed(app, event)
            app.motion.StopImmediate(0);
            app.move = false;
        end

        % Button pushed function: ExperimentalTestButton
        function ExperimentalTestButtonPushed(app, event)
            app.Optimizetest();
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MotionControlPanel = uipanel(app.UIFigure);
            app.MotionControlPanel.Title = 'Motion Control';
            app.StartButton = uibutton(app.MotionControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopImmediateButton = uibutton(app.MotionControlPanel, 'push');
            app.StopImmediateButton.ButtonPushedFcn = createCallbackFcn(app, @StopImmediateButtonPushed, true);
            app.StopImmediateButton.Text = 'StopImmediate';
            app.ExperimentalTestButton = uibutton(app.MotionControlPanel, 'push');
            app.ExperimentalTestButton.ButtonPushedFcn = createCallbackFcn(app, @ExperimentalTestButtonPushed, true);
            app.ExperimentalTestButton.Text = 'Experimental Test';
            app.LockinPanel = uipanel(app.UIFigure);
            app.LockinPanel.Title = 'Lockin';
            app.UIAxes = uiaxes(app.LockinPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.MoveandRecordButton = uibutton(app.LockinPanel, 'push');
            app.MoveandRecordButton.ButtonPushedFcn = createCallbackFcn(app, @MoveandRecordButtonPushed, true);
            app.MoveandRecordButton.Text = 'Move and Record';
            app.ResetGraphButton = uibutton(app.LockinPanel, 'state');
            app.ResetGraphButton.ValueChangedFcn = createCallbackFcn(app, @ResetGraphButtonValueChanged, true);
            app.ResetGraphButton.Text = 'Reset Graph';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Motioncontrolv4

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end