classdef ForceDisplacementv2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ContactTab                   matlab.ui.container.Tab
        Panel                        matlab.ui.container.Panel
        ResetGraphButton             matlab.ui.control.StateButton
        MoveandRecordButton          matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        MotionControlPanel           matlab.ui.container.Panel
        StopImmediatelyButton        matlab.ui.control.Button
        StartButton                  matlab.ui.control.Button
        FindContactPointButton       matlab.ui.control.Button
        InitialParametersTab         matlab.ui.container.Tab
        PositionAlterationPanel      matlab.ui.container.Panel
        StartPositionEditField       matlab.ui.control.NumericEditField
        StartPositionEditFieldLabel  matlab.ui.control.Label
        SetPositionasMaximumLimitButton  matlab.ui.control.Button
        MoveBackwardButtonGroup      matlab.ui.container.ButtonGroup
        mmButton_6                   matlab.ui.control.ToggleButton
        mmButton_5                   matlab.ui.control.ToggleButton
        mmButton_4                   matlab.ui.control.ToggleButton
        MoveForwardButtonGroup       matlab.ui.container.ButtonGroup
        mmButton                     matlab.ui.control.ToggleButton
        mmButton_2                   matlab.ui.control.ToggleButton
        mmButton_3                   matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        motion; % NRT100
        forceSensor; % Lock in Amplifier
        lockin; % Lock in Object
        Amplitude = zeros(1,1000); % List of Amplitude(R) displayed
        Position = zeros(1,1000) % List of Position displayed
        counter = 0; % Index for the Amplitude and Position Graph
        inc = 0.1;  %Increment for running the Motion Stage
        slope = zeros(1,1000); %Slope initialized in Experimental Test
        move = true; % When it sets to False, it stops the Experiemental Setup function
        threshold; % minimum amplitude to stop experiment
        StartPosition = 90; %Start position at the start sequence
        maxPos = 90;
    end
    
    methods (Access = private)
        function WaitForMotionDone(app) % Wait until motion is complete
            while true
                status = int64(app.motion.GetStatusBits_Bits(0)); % Assuming channel 0
                %if bitand(status, hex2dec('00000800')) % Check if bit 11 is set
                    % break;
                %end
                if bitget(status, 11) % Check if bit 11 is 1 and 1 is true
                    break % Exit the loop
                end
                pause(0.1) % Wait for 0.1 seconds before checking again
            end
        end
        function val = MakeMeasurement(app)
            Measurement = zeros(1,5); % Used to Average Measurements
            for i = 1:5
                fprintf(app.lockin, 'OUTP? 3'); %Set OUTPUT to R value;
                Measurement(i) = fscanf(app.lockin, '%f');
            end
            val = mean(Measurement);
        end
            
                
        function StageActivation(app)
            fpos    = get(0,'DefaultFigurePosition'); % figure default position
            fpos(3) = 650*2/3; % figure window size;Width
            fpos(4) = 450*2/3; % Height

            fig = figure('Position', fpos,...
               'Menu','None',...
               'Name','APT GUI');

            app.motion = actxcontrol('MGMOTOR.MGMotorCtrl.1',[20 20 400 400*2/3 ], fig); %Creating motion app
            app.motion.StartCtrl;
            SN2 = 90914852; %The seriel number for the Channel connected
            set(app.motion,'HWSerialNum', SN2);
            jFrame2 = get(fig,'JavaFrame'); 
            jFrame2.setMinimized(true);
            
        end
        
        function LockIn(app)
            % Use instrfind to find existing instrument objects
            app.lockin = instrfind('Type', 'visa-gpib', 'RsrcName', 'GPIB0::8::INSTR', 'Tag', '');
            % If there are no existing objects q, create a new one
            if isempty(app.lockin)
                app.lockin = visa('ni', 'GPIB0::8::INSTR');
            end
            fopen(app.lockin);
            fprintf(app.lockin, 'OFLT 6'); % Set the time constant to 10ms
            fprintf(app.lockin, 'SLVL 0.1'); % Set the amplitude to 0.1V
            fprintf(app.lockin, 'OFSL 3'); % Low Pass Filter Slope
            fprintf(app.lockin, 'SYNC 1'); % Set Sync on (1)
            fprintf(app.lockin, 'SENS 23'); % Change for later 
            fprintf(app.lockin, 'HARM 1'); % Set Detection Harmonic (Value)
            fprintf(app.lockin, 'FREQ 100'); %Set Frequency 100 Hz
            
        end
        function Move(app)
            curPos = app.motion.GetPosition_Position(0); 
            curinc = app.motion.GetRelMoveDist_RelDist(0);
            if (curPos + curinc > app.maxPos)
                return
            end
            app.motion.MoveRelative(0, true); 
        end
        function DisplayRecordAmp(app)
            pause(1);
            app.counter = app.counter + 1;
            %fprintf(app.lockin, 'OUTP? 3'); %Set OUTPUT to R value;
            app.Amplitude(app.counter) = app.MakeMeasurement(); % Read float values 
            app.Position(app.counter) = app.motion.GetPosition_Position(0); %Getting Position in mm
            xlabel(app.UIAxes,'Position');
            ylabel(app.UIAxes,'Amplitude');
            if (app.counter ~= 1) 
                app.slope(app.counter) = app.FindSlope();
            end
            scatter(app.UIAxes,app.Position(1:app.counter), app.Amplitude(1:app.counter));
        end
        function Optimizetest(app)
            app.move =true;
            app.DisplayRecordAmp(); % Plot amplitude and position
            app.Move();
            app.DisplayRecordAmp();
            app.Move();
            app.DisplayRecordAmp();
            
            while(app.slope(app.counter) >= 0.9 * app.slope(app.counter -1) || app.Amplitude(app.counter) < app.threshold)
                if(app.move == false)
                    break;
                end
                app.Move();
                pause(1);
                app.DisplayRecordAmp();
            end
            app.motion.SetRelMoveDist(0, -1.5 * app.inc);
            app.Move()
            app.DisplayRecordAmp();
            app.motion.SetRelMoveDist(0, 0.005);
            for c = 1:30
                if(app.move == false)
                    break;
                end
                app.Move();
                app.DisplayRecordAmp();
                %acc = (app.slope - Prevslope) / (app.Position(app.counter) - app.Position(app.counter - 1));
                %if ( acc < 0 || (acc > 10 && app.inc < 0))
                   % app.inc = app.inc * -0.5;
                %end
            end
            change = ischange(app.Amplitude(app.counter-29:app.counter),'linear','MaxNumChanges',1);
            changeindex = find(change,1,'last') + 1;
            pos = app.Position(app.counter - 29 + changeindex);
            %coef = polyfit(app.Position(app.counter - 29: app.counter), app.slope(app.counter - 29: app.counter), 2);
            %pos = -coef(2)/ (2* coef(1));
            app.motion.SetAbsMovePos(0, pos);
            app.motion.MoveAbsolute(0, true);
        end
        function newslope = FindSlope(app)
            newslope = (app.Amplitude(app.counter) - app.Amplitude(app.counter -1)) / (app.Position(app.counter) - app.Position(app.counter -1));
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.LockIn();
            app.StageActivation();
            app.threshold = MakeMeasurement(app) * 15;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.motion.SetAbsMovePos(0, app.StartPosition);
            app.motion.SetVelParams(0,0, 0.5 , 2.0);
            app.motion.SetRelMoveDist(0, app.inc);
            app.motion.MoveHome(0,false); 
            pause(1);
            app.WaitForMotionDone();
            app.motion.MoveAbsolute(0,true);
        end

        % Button pushed function: MoveandRecordButton
        function MoveandRecordButtonPushed(app, event)
            app.motion.MoveRelative(0, true);
            pause(3);
            app.DisplayRecordAmp();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            fclose(app.lockin);
            delete(app.motion);
            delete(app);
        end

        % Value changed function: ResetGraphButton
        function ResetGraphButtonValueChanged(app, event)
            position = app.Position(2:app.counter);
            amplitude = app.Amplitude(2:app.counter);
            
            % Sort the data by ascending position
            %[position_sorted, idx] = sort(position);
            %amplitude_sorted = amplitude(idx);
            
            % Combine the sorted data into a matrix
            data = [amplitude' position'];
            
            % Save the sorted data into a CSV file
            filename = 'sorted_data.csv';
            csvwrite(filename, data); 
            app.Amplitude = zeros(100,1);
            app.Position = zeros(100,1);
            app.counter = 0;
            app.inc = 0.2;
            app.motion.SetRelMoveDist(0, app.inc);
        end

        % Button pushed function: StopImmediatelyButton
        function StopImmediatelyButtonPushed(app, event)
            app.motion.StopImmediate(0);
            app.move = false;
        end

        % Button pushed function: FindContactPointButton
        function FindContactPointButtonPushed(app, event)
            app.Optimizetest();
        end

        % Selection changed function: MoveForwardButtonGroup
        function MoveForwardButtonGroupSelectionChanged(app, event)
            selectedButton = app.MoveForwardButtonGroup.SelectedObject;
            if selectedButton == app.mmButton_3
                app.motion.SetRelMoveDist(0, 0.02);
            elseif selectedButton == app.mmButton_2
                app.motion.SetRelMoveDist(0, 0.1); 
            elseif selectedButton == app.mmButton
                app.motion.SetRelMoveDist(0, 1);
            end
            app.motion.MoveRelative(0,true);
        end

        % Selection changed function: MoveBackwardButtonGroup
        function MoveBackwardButtonGroupSelectionChanged(app, event)
            selectedButton = app.MoveBackwardButtonGroup.SelectedObject;
            if selectedButton == app.mmButton_4
                app.motion.SetRelMoveDist(0, -0.02);
            elseif selectedButton == app.mmButton_5
                app.motion.SetRelMoveDist(0, -0.1);
            elseif selectedButton == app.mmButton_6
                app.motion.SetRelMoveDist(0, -1);
            end
            app.motion.MoveRelative(0,true);
        end

        % Value changed function: StartPositionEditField
        function StartPositionEditFieldValueChanged(app, event)
            app.StartPosition = app.StartPositionEditField.Value;
        end

        % Button pushed function: SetPositionasMaximumLimitButton
        function SetPositionasMaximumLimitButtonPushed(app, event)
            app.maxPos = app.motion.GetPosition_Position(0);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ContactTab = uitab(app.TabGroup);
            app.ContactTab.Title = 'Contact';
            app.MotionControlPanel = uipanel(app.ContactTab);
            app.MotionControlPanel.Title = 'Motion Control';
            app.FindContactPointButton = uibutton(app.MotionControlPanel, 'push');
            app.FindContactPointButton.ButtonPushedFcn = createCallbackFcn(app, @FindContactPointButtonPushed, true);
            app.FindContactPointButton.Text = 'Find Contact Point';
            app.StartButton = uibutton(app.MotionControlPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopImmediatelyButton = uibutton(app.MotionControlPanel, 'push');
            app.StopImmediatelyButton.ButtonPushedFcn = createCallbackFcn(app, @StopImmediatelyButtonPushed, true);
            app.StopImmediatelyButton.Text = 'Stop Immediately';
            app.Panel = uipanel(app.ContactTab);
            app.UIAxes = uiaxes(app.Panel);
            title(app.UIAxes, 'Current Amplitude')
            xlabel(app.UIAxes, 'Position')
            ylabel(app.UIAxes, 'Amplitude')
            app.MoveandRecordButton = uibutton(app.Panel, 'push');
            app.MoveandRecordButton.ButtonPushedFcn = createCallbackFcn(app, @MoveandRecordButtonPushed, true);
            app.MoveandRecordButton.Text = 'Move and Record';
            app.ResetGraphButton = uibutton(app.Panel, 'state');
            app.ResetGraphButton.ValueChangedFcn = createCallbackFcn(app, @ResetGraphButtonValueChanged, true);
            app.ResetGraphButton.Text = 'Reset Graph';
            app.InitialParametersTab = uitab(app.TabGroup);
            app.InitialParametersTab.Title = 'Initial Parameters';
            app.MoveForwardButtonGroup = uibuttongroup(app.InitialParametersTab);
            app.MoveForwardButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MoveForwardButtonGroupSelectionChanged, true);
            app.MoveForwardButtonGroup.Title = 'Move Forward';
            app.mmButton_3 = uitogglebutton(app.MoveForwardButtonGroup);
            app.mmButton_3.Text = '0.02mm';
            app.mmButton_3.Value = true;
            app.mmButton_2 = uitogglebutton(app.MoveForwardButtonGroup);
            app.mmButton_2.Text = '0.1mm';
            app.mmButton = uitogglebutton(app.MoveForwardButtonGroup);
            app.mmButton.Text = '1mm';
            app.MoveBackwardButtonGroup = uibuttongroup(app.InitialParametersTab);
            app.MoveBackwardButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MoveBackwardButtonGroupSelectionChanged, true);
            app.MoveBackwardButtonGroup.Title = 'Move Backward';
            app.mmButton_4 = uitogglebutton(app.MoveBackwardButtonGroup);
            app.mmButton_4.Text = '0.02mm';
            app.mmButton_4.Value = true;
            app.mmButton_5 = uitogglebutton(app.MoveBackwardButtonGroup);
            app.mmButton_5.Text = '0.1mm';
            app.mmButton_6 = uitogglebutton(app.MoveBackwardButtonGroup);
            app.mmButton_6.Text = '1mm';
            app.PositionAlterationPanel = uipanel(app.InitialParametersTab);
            app.PositionAlterationPanel.Title = 'Position Alteration';
            app.SetPositionasMaximumLimitButton = uibutton(app.PositionAlterationPanel, 'push');
            app.SetPositionasMaximumLimitButton.ButtonPushedFcn = createCallbackFcn(app, @SetPositionasMaximumLimitButtonPushed, true);
            app.SetPositionasMaximumLimitButton.Text = 'Set Position as Maximum Limit';
            app.StartPositionEditFieldLabel = uilabel(app.PositionAlterationPanel);
            app.StartPositionEditFieldLabel.Text = 'Start Position';
            app.StartPositionEditField = uieditfield(app.PositionAlterationPanel, 'numeric');
            app.StartPositionEditField.Limits = [0 98];
            app.StartPositionEditField.ValueChangedFcn = createCallbackFcn(app, @StartPositionEditFieldValueChanged, true);
            app.StartPositionEditField.Value = 90;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ForceDisplacementv2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end